classdef AutomoDQA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        uipanelTomoDQA                 matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        SaveResult_2                   matlab.ui.control.Button
        APuncertaintySwitch            matlab.ui.control.Switch
        APuncertaintySwitchLabel       matlab.ui.control.Label
        AutoRun                        matlab.ui.control.Button
        ShowRes                        matlab.ui.control.Button
        SelectProfileCheckBox          matlab.ui.control.CheckBox
        ShowROICheckBox                matlab.ui.control.CheckBox
        pushbuttonCalculateGamma_3     matlab.ui.control.Button
        FilmScaling                    matlab.ui.control.NumericEditField
        FilmScaleLabel                 matlab.ui.control.Label
        APadjustmmLabel                matlab.ui.control.Label
        APUncertaintyLevel             matlab.ui.control.NumericEditField
        PBirthday                      matlab.ui.control.EditField
        text9_3                        matlab.ui.control.Label
        PSex                           matlab.ui.control.EditField
        text9_2                        matlab.ui.control.Label
        RobustregistrationSwitch_2Label  matlab.ui.control.Label
        RobustregistrationSwitch_2     matlab.ui.control.Switch
        TabGroup                       matlab.ui.container.TabGroup
        DGammaanalysisTab              matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        MeasureddoseTab                matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        CaclulateddoseTab              matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        InformationTab                 matlab.ui.container.Tab
        SavepathEditField              matlab.ui.control.EditField
        SavepathEditFieldLabel         matlab.ui.control.Label
        ConfigurationEditField         matlab.ui.control.EditField
        ConfigurationEditFieldLabel    matlab.ui.control.Label
        FilmCalibrationEditField       matlab.ui.control.EditField
        FilmCalibrationEditFieldLabel  matlab.ui.control.Label
        SelectedfolderEditField        matlab.ui.control.EditField
        SelectedfolderEditFieldLabel   matlab.ui.control.Label
        Dmax_cal                       matlab.ui.control.EditField
        text3_7                        matlab.ui.control.Label
        BestconfigurationPanel         matlab.ui.container.Panel
        text3_6                        matlab.ui.control.Label
        text3_4                        matlab.ui.control.Label
        BestFilmScalingFactor          matlab.ui.control.EditField
        BestAPuncertainty              matlab.ui.control.EditField
        GammapassratePanel             matlab.ui.container.Panel
        Mgpr                           matlab.ui.control.Label
        PF                             matlab.ui.control.Label
        ConfigPanel                    matlab.ui.container.Panel
        pushbuttonCalculateGamma_2     matlab.ui.control.Button
        ConfigRoiSize                  matlab.ui.control.NumericEditField
        maxLabel                       matlab.ui.control.Label
        minLabel                       matlab.ui.control.Label
        DefaultLabel                   matlab.ui.control.Label
        AP_default                     matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        AP_max                         matlab.ui.control.NumericEditField
        AP_min                         matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        APuncertaintymmLabel           matlab.ui.control.Label
        FilmscalingfactorLabel         matlab.ui.control.Label
        ROIcropsizeLabel               matlab.ui.control.Label
        edit1_6                        matlab.ui.control.EditField
        edit1_5                        matlab.ui.control.EditField
        TomoMachine                    matlab.ui.control.EditField
        text3_2                        matlab.ui.control.Label
        pushbuttonSelectFilmCalFile    matlab.ui.control.Button
        MRN                            matlab.ui.control.EditField
        SaveResult                     matlab.ui.control.Button
        text3                          matlab.ui.control.Label
        text4                          matlab.ui.control.Label
        text9                          matlab.ui.control.Label
        SaveConfiguration              matlab.ui.control.Button
        OpenConfiguration              matlab.ui.control.Button
        uipanelGammaAnalysis           matlab.ui.container.Panel
        editGPRTolerance               matlab.ui.control.EditField
        text12_3                       matlab.ui.control.Label
        editLowDoseTolerance           matlab.ui.control.EditField
        text12_2                       matlab.ui.control.Label
        editDoseTolerance              matlab.ui.control.EditField
        editDTAtolerance               matlab.ui.control.EditField
        text12                         matlab.ui.control.Label
        text11                         matlab.ui.control.Label
        pushbuttonSelectPatient        matlab.ui.control.Button
        NumFrac                        matlab.ui.control.EditField
        PName                          matlab.ui.control.EditField
        axes2_2                        matlab.ui.control.UIAxes
        axes2                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function chooseAPloc(app, source, event)
            
            global FilmAPLocation
            [~,FilmAPLocation] = ginput(1);
            FilmAPLocation
        end
        
        function chooseRotationCenterXY(app, source, event)
            
            global RotationCenterX
            global RotationCenterY
            [RotationCenterX,RotationCenterY] = ginput(1);
        end
        
        function chooseRotationCenterZ(app, source, event)
            
            global RotationCenterZ
            [~,RotationCenterZ] = ginput(1);
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        
        function SetGPRResult(app, Res_FilmScalingFactor, Res_Dmax, Res_APUncertainty, Res_gpr)
            % Set GPR Result
            uncertainty_format = sprintf("%1.1f", Res_APUncertainty);            
            Dmax_cal_format = sprintf("%3.3f", Res_Dmax);
            format_PF = sprintf("%2.2f %%", Res_gpr*100);
            
            set(app.BestFilmScalingFactor, 'value', num2str(Res_FilmScalingFactor), 'fontcolor', 'black', 'fontname', 'Calibri');
            set(app.BestAPuncertainty, 'value', uncertainty_format);
%             set(app.Dmax_cal, 'value', Dmax_cal_format, 'fontname', 'Calibri');
            
            gpr_cutoff_percent = str2double(app.editGPRTolerance.Value);
            if Res_gpr >= gpr_cutoff_percent/100
                set(app.PF, 'text', 'PASS', 'fontcolor', 'blue', 'fontname', 'Calibri');
                set(app.Mgpr, 'text', format_PF, 'fontcolor', 'blue', 'fontname', 'Calibri');
            else
                set(app.PF, 'text', 'FAIL', 'fontcolor', 'red', 'fontname', 'Calibri');
                set(app.Mgpr, 'text', format_PF, 'fontcolor', 'red', 'fontname', 'Calibri');
            end            
            
        end
        
        function DrawImg(app, HandleImg, TargetImg, x_line, y_line, XLim, YLim, CLim, colormap_)
            % Draw Main Img
            cla(HandleImg);
            
            % some settings 
            set(HandleImg, 'Units', 'pixels');
            set(HandleImg, 'Units', 'normalized');
            HandleImg.XLim = [0 XLim];
            HandleImg.YLim = [0 YLim];
            load("GammaColor.mat"); % 0 - 0.996 / if 0-1.5
            
            % drawing     
            imagesc(HandleImg, TargetImg)
            colorbar(HandleImg)
            xline(HandleImg, x_line, 'g--');
            yline(HandleImg, y_line, 'y--');
            caxis(HandleImg, [0, CLim]);
            if strcmp(colormap_, 'custom')
                colormap(HandleImg, GammaColor);
            else
                colormap(HandleImg, 'jet');
            end
            legend(HandleImg, 'line profile (vertical)', 'line profile (horizontal)', 'Color', [0.752, 0.752, 0.752]);            
            
        end
                
        
        function DrawLine(app, HandleAxis, line_dose, line_film, line_gamma)
            
            %% draw lines
            cla(HandleAxis)
            plot(HandleAxis, line_dose, 'b-');            
            plot(HandleAxis, line_film, 'g-');
            plot(HandleAxis, line_gamma);
            yline(HandleAxis, 1, 'r-');            
        end
        
        function [interp_film] = FilmLoader(app, film_path, crop_edge_size)
            global filmcal_data
            global FilmSpacing                        
            global size_film              
            
            filename_film = film_path;
            t = Tiff(filename_film);
            film = read(t);
            film = flipud(film);
            film = double(film);
            film_b = 2^16 - film;
        
            % median filtering
            film = medfilt2(film_b, [5 5]);
            try
                filmdose = interp1(filmcal_data(:,2), filmcal_data(:,1), double(film), 'spline');
            catch 
%                 msg = ['Failed to film calibration',' ','please check film calibration file path'];
%                 causeException=MException('MATLAB:myCode:dimensions',msg);
%                 ME = addCause(ME, causeException);
%                 rethrow(ME)
                warning("Failed to calibrate film, please check film calibration file path and type.");
                return
            end
        
            % film resolution: 71 dpi (dots per inch)  
            %       71 dots / 1 inch = 71 dots / 25.4 mm
            FilmSpacing = 25.4/71;   % pixel size (mm)
            size_film = size(film);
        
            xindex_film = (1:size_film(2))';
            zindex_film = (1:size_film(1))';
            
            x_film = FilmSpacing*xindex_film;
            z_film = FilmSpacing*zindex_film;
            
            [xx_film, zz_film] = meshgrid(x_film, z_film);
            [xx_film_interp, zz_film_interp] = meshgrid(x_film, z_film);    
            interp_film = interp2(xx_film, zz_film, filmdose, xx_film_interp, zz_film_interp, 'spline');
            interp_film = interp_film/100; % cGY
            
            if crop_edge_size==0.5
                % crop 5mm
                interp_film(1:2, :) = 0;
                interp_film(size(interp_film,1)-2:end, :) = 0;
                interp_film(:, 1:3) = 0;
                interp_film(:, size(interp_film,2)-3:end) = 0;
            else
                % crop 1cm
                interp_film(1:4, :) = 0;
                interp_film(size(interp_film,1)-4:end, :) = 0;
                interp_film(:, 1:6) = 0;
                interp_film(:, size(interp_film,2)-6:end) = 0;
            end
        end

        function [gamma2D, gpr] = GammaAnalyzer(app, film, dose, film_scale_factor, x_from, y_from, x_to, y_to, DTA_percent, DD_percent, LDcutoff_percent)
            global Dmax
            size_film = size(film);
%             Dmax = max(max(dose));
%             disp(max(max(dose)))
            near_size = 3;
                        
            film_scaled = film * film_scale_factor;
            
            gamma2D = GetGamma2D(dose, film_scaled, DTA_percent, DD_percent, LDcutoff_percent, Dmax, near_size*2, near_size*2, size_film(2)-near_size*2, size_film(1)-near_size*2, near_size);
            gpr = CalGPR(gamma2D(x_from:x_to, y_from:y_to), dose(x_from:x_to, y_from:y_to), Dmax, LDcutoff_percent);
        end
        
        function [gammas, gpr_maxs, factors] = GammaAnalyzerLoop(app, film, dose, x_from, y_from, x_to, y_to, DTA_percent, DD_percent, LDcutoff_percent)
            global Dmax
            num_len = 21;
            gammas = cell(1, num_len);
            factors = cell(1, num_len);
            gpr_maxs = cell(1,num_len);
            intervals = linspace(0.9, 1.1, num_len);
            
            near_size = 3;
            size_film = size(film);            
            x_end = size_film(2)-near_size*2;
            y_end = size_film(1)-near_size*2;
%             Dmax = max(max(dose));
            
            
            parfor idx=1:length(intervals)        
                mulfactor=intervals(idx);                                
                        
                film_scaled = film * mulfactor;
                
                gamma2D = GetGamma2D(dose, film_scaled, DTA_percent, DD_percent, LDcutoff_percent, Dmax, near_size*2, near_size*2, x_end, y_end, near_size);
                gpr = CalGPR(gamma2D(x_from:x_to, y_from:y_to), dose(x_from:x_to, y_from:y_to), Dmax, LDcutoff_percent);    
                
                gammas{idx} = gamma2D;
                gpr_maxs{idx} = gpr;
                factors{idx} = mulfactor;
            end    
        
        end
        function DrawAllResults(app, film, dose, gamma_max, factor_best)
            
            global roi_point
            global bool_imageshown
            global rgamma
            global rmeasured
            global rcalculated
            
            Dmax = max(max(dose));            
            size_film = size(film);

            if ishandle(rgamma)
                % ROI defined already
                pos = rgamma.Position;            
                [y_from, x_from, width, height] = deal(pos(1), pos(2), pos(3), pos(4));
                x_to = x_from + height;
                y_to = y_from + width;
            else
                % ROI default
                config_roi_size_in_perecent = app.ConfigRoiSize.Value;
                percentage_edge_offset = config_roi_size_in_perecent/100;
                x_from = floor(size_film(1)*percentage_edge_offset);
                y_from = floor(size_film(2)*percentage_edge_offset);
                x_to = size_film(1)-x_from;
                y_to = size_film(2)-y_from;
                width = floor(size_film(2)-y_from*2);
                height = floor(size_film(1)-x_from*2);
            end            
%             config_roi_size_in_perecent = app.ConfigRoiSize.Value;
%             percentage_edge_offset = config_roi_size_in_perecent/100;
%             size_x_offset = floor(size_film(2)*percentage_edge_offset);
%             size_y_offset = floor(size_film(1)*percentage_edge_offset);
%             x_end = size_film(2)-size_x_offset;
%             y_end = size_film(1)-size_y_offset;
            
%             filmsize_x = round(size(film,1)*percentage_edge_offset);
%             filmsize_y = round(size(film,2)*percentage_edge_offset);        
            
            % 1cm crop
            film_line = film*factor_best;
            film_line(1:4, :) = 0;
            film_line(:, 1:6) = 0;
            film_line(end-4:end, :) = 0;
            film_line(:, end-6:end) = 0;

            film_rescale = film * factor_best;
            LDtolerance = str2num(app.editLowDoseTolerance.Value);
            mask_dose = dose >  Dmax*LDtolerance/100;
            mgamma = gamma_max.*mask_dose;
            
            % find Dmax line profile
            if ishandle(roi_point)
                roi_pos= roi_point.Position;
                x_line = round(roi_pos(1));
                y_line = round(roi_pos(2));
            else
                [y_line, x_line] = find(dose == max(max(dose)));
            end
            
                        
            hold(app.UIAxes, 'on');
            hold(app.UIAxes_2, 'on');
            hold(app.UIAxes_3, 'on');
            hold(app.axes2, 'on');
            hold(app.axes2_2, 'on'); 
            
            % Draw gamma map / Measured dose / Calculated dose
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.UIAxes_2);
            disableDefaultInteractivity(app.UIAxes_3);
            app.DrawImg(app.UIAxes, mgamma, x_line, y_line, size(mgamma, 2), size(mgamma, 1), 1.5, 'custom')
            app.DrawImg(app.UIAxes_2, film_rescale, x_line, y_line, size(mgamma, 2), size(mgamma, 1), Dmax, 'jet')
            app.DrawImg(app.UIAxes_3, dose, x_line, y_line, size(mgamma, 2), size(mgamma, 1), Dmax, 'jet')
            rgamma = drawrectangle(app.UIAxes, 'position', [y_from, x_from, width, height], 'Color', [1, 1, 1], 'FaceAlpha', 0);
            rmeasured = drawrectangle(app.UIAxes_2, 'position', [y_from, x_from, width, height], 'Color', [1, 1, 1], 'FaceAlpha', 0);
            rcalculated = drawrectangle(app.UIAxes_3, 'position', [y_from, x_from, width, height], 'Color', [1, 1, 1], 'FaceAlpha', 0);
%             rgamma = rectangle(app.UIAxes, 'position', [size_x_offset, size_y_offset, square_x_len, square_y_len], 'EdgeColor', 'y', 'LineStyle', "-.", 'EdgeColor', [.3, .3, .3]); 
%             rmeasured = rectangle(app.UIAxes_2, 'position', [size_x_offset, size_y_offset, square_x_len, square_y_len], 'EdgeColor', 'y', 'LineStyle', "-.", 'EdgeColor', [.3, .3, .3]); 
%             rcalculated = rectangle(app.UIAxes_3, 'position', [size_x_offset, size_y_offset, square_x_len, square_y_len], 'EdgeColor', 'y', 'LineStyle', "-.", 'EdgeColor', [.3, .3, .3]); 
            
            % Draw axes
            dose_xg = dose(:, x_line);
            film_xg = film_line(:, x_line);
            dose_yg = dose(y_line, :);
            film_yg = film_line(y_line, :);
            gammax = mgamma(:, x_line);
            gammay = mgamma(y_line, :);
            
            ylimit = max(max(film_yg, [], 'all'), max(dose_yg, [], 'all'));
            app.DrawLine(app.axes2, dose_xg, film_xg, gammax);            
            xlim(app.axes2, [0, size_film(1)+1])
            ylim(app.axes2, [0, ylimit+0.5])
            legend(app.axes2, 'calculated dose', 'measured dose','gamma index', 'gamma index criteria');
                        
            app.DrawLine(app.axes2_2, dose_yg, film_yg, gammay);
            xlim(app.axes2_2, [0, size_film(2)+1])
            ylim(app.axes2_2, [0, ylimit+0.5])
            legend(app.axes2_2, 'calculated dose', 'measured dose', 'gamma index', 'gamma index criteria');
            
            bool_imageshown=true;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Visualization %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            if app.ShowROICheckBox.Value
               rgamma.Visible = true;
               rmeasured.Visible = true; 
               rcalculated.Visible = true;
            else
               rgamma.Visible = false;
               rmeasured.Visible = false; 
               rcalculated.Visible = false;                
            end
            
            
        end
        
        function WatchROI(app, src, evt)
            global rgamma
            global rmeasured
            global rcalculated

            rgamma.Position = src.Position;
            rmeasured.Position = src.Position;
            rcalculated.Position = src.Position;            
%             disp(['ROI moved Previous Position: ' mat2str(evt.PreviousPosition)]);
%             disp(['ROI moved Current Position: ' mat2str(evt.CurrentPosition)]);            
        end
        
        function MoveROI(app, pos)
            global rgamma
            global rmeasured
            global rcalculated
            
            rgamma.Position = pos;
            rmeasured.Position = pos;
            rcalculated.Position = pos;
        end
                 
        function [interp_film, interp_dose_new, gamma_max, factor_best, gpr_max] = runDQA(app, film, info, golden_length, f, bool_waitbar, bool_save, best_gpr)
            
            global Dmax
            global Dmax3D
            global gamma_max

            global interp_dose_new
            global interp_film
            global interp_film_avg            
            
            global size_film
            global FilmSpacing
            global bool_imageshown
            global factor_best
            global json_struct
            
            global rgamma
            global rmeasured
            global rcalculated
            
            global best_figure
                        
            APUncertainty = golden_length-94.7;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Dose %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% Load file
            rtplan_info = dicominfo(info.RTPlan);
            rtdose_info = dicominfo(info.RTDose);

            %%% Find film AP position
            ry = rtplan_info.PatientSetupSequence.Item_1.SetupDeviceSequence.Item_2.SetupDeviceParameter;
            golden_pos = ry+golden_length;
                        
            %%% RT dose
            RTDoseGridSpacing(1:2) = rtdose_info.PixelSpacing;
            RTDoseGridSpacing(3) = rtdose_info.SliceThickness;
            RTDoseGridScaling = rtdose_info.DoseGridScaling;
            RTDoseOrigin = rtdose_info.ImagePositionPatient;
            
            NumberOfFractions = rtplan_info.FractionGroupSequence.Item_1.NumberOfFractionsPlanned;
            rtdose_data = dicomread(rtdose_info);
            rtdose_data = squeeze(rtdose_data);
            rtdose = (RTDoseGridScaling/NumberOfFractions).*double(rtdose_data);
            rtdose = flip(rtdose,3);
%             Dmax = max(rtdose, [], "all");
            RTDoseGridSize = size(rtdose);
        
            xindex_rtdose = (1:RTDoseGridSize(2))';
            yindex_rtdose = (1:RTDoseGridSize(1))';
            zindex_rtdose = (1:RTDoseGridSize(3))';
        
            x_rtdose = RTDoseOrigin(1) + RTDoseGridSpacing(1)*(xindex_rtdose - 1);
            y_rtdose = RTDoseOrigin(2) + RTDoseGridSpacing(2)*(yindex_rtdose - 1);
            z_rtdose = RTDoseOrigin(3) - RTDoseGridSpacing(3)*(zindex_rtdose - 1);
            z_rtdose = flip(z_rtdose,1);
        
            [xx_dose, yy_dose, zz_dose] = meshgrid(x_rtdose, y_rtdose, z_rtdose);
            [xx_dose_interp, yy_dose_interp, zz_dose_interp] = meshgrid(x_rtdose, golden_pos, z_rtdose);
            
            dose_interp_raw = interp3(xx_dose, yy_dose, zz_dose, rtdose, xx_dose_interp, yy_dose_interp, zz_dose_interp, 'spline');
            interp_dose= rot90(squeeze(dose_interp_raw), -1);
            interp_dose = flip(interp_dose,2);
            
        % Crop edges                                        
        %     interp_dose_crop = interp_dose(10:size(interp_dose,1)-10, 16:size(interp_dose,2)-16); % 3 cm
%             interp_dose_crop = interp_dose(4:size(interp_dose,1)-4, 6:size(interp_dose,2)-6); % 1 cm
        %     interp_dose_crop = interp_dose(2:size(interp_dose,1)-2, 3:size(interp_dose,2)-3); % 5 mm
            
            interp_dose_crop = interp_dose;            
            Dmax = max(interp_dose_crop, [], 'all');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% /Dose %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if bool_waitbar
                waitbar(0.70, f, 'Wait for registration...');
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Registration %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            
            RD = imref2d(size(interp_dose_crop), RTDoseGridSpacing(2), RTDoseGridSpacing(3));
            RF = imref2d(size(film), FilmSpacing, FilmSpacing);
            
            RD.XWorldLimits = [-RD.ImageExtentInWorldX/2, RD.ImageExtentInWorldX/2];
            RD.YWorldLimits = [-RD.ImageExtentInWorldY/2, RD.ImageExtentInWorldY/2];
            RF.XWorldLimits = [-RF.ImageExtentInWorldX/2, RF.ImageExtentInWorldX/2];
            RF.YWorldLimits = [-RF.ImageExtentInWorldY/2, RF.ImageExtentInWorldY/2];    
            
            [optimizer, metric] = imregconfig('multimodal');
            % optimizer.InitialRadius = 0.009;
            optimizer.InitialRadius = 6e-3;
            optimizer.Epsilon = 1.5e-6;
            % optimizer.GrowthFactor = 1.05;            
            if strcmpi(get(app.RobustregistrationSwitch_2, 'value'), 'On')                
                optimizer.MaximumIterations = 200;
            else
                optimizer.MaximumIterations = 100;
            end
            interp_dose_new = imregister(interp_dose_crop, RD, film, RF, 'rigid', optimizer, metric);
            
            interp_film_avg = film;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% /Registration %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            
            if bool_waitbar
                waitbar(0.88, f, 'Wait for gamma analysis...');
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Gamma analysis %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
                        
                                    
            if ishandle(rgamma)
                % ROI defined already
                pos = rgamma.Position;            
                [y_from, x_from, width, height] = deal(round(pos(1)), round(pos(2)), round(pos(3)), round(pos(4)));
                x_to = x_from + height;
                y_to = y_from + width;
            else
                % ROI default
                config_roi_size_in_perecent = app.ConfigRoiSize.Value;
                percentage_edge_offset = config_roi_size_in_perecent/100;
                x_from = floor(size_film(1)*percentage_edge_offset);
                y_from = floor(size_film(2)*percentage_edge_offset);
                x_to = size_film(1)-x_from;
                y_to = size_film(2)-y_from;
            end
            
            DTA_percent = str2double(app.editDTAtolerance.Value);
            DD_percent = str2double(app.editDoseTolerance.Value);
            LDcutoff_percent = str2double(app.editLowDoseTolerance.Value);
            
            % investigate best gpr in multiple film scaling factor
            [gammas, gpr_maxs, factors] = app.GammaAnalyzerLoop(interp_film_avg, interp_dose_new, x_from, y_from, x_to, y_to, DTA_percent, DD_percent, LDcutoff_percent);
                        
            gpr_max = max(cell2mat(gpr_maxs));
            max_index = find(cell2mat(gpr_maxs)==gpr_max);
            factor_best = factors{max_index};
            gamma_max = gammas{max_index};
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Gamma analysis %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%% Set info %%%
            app.SetGPRResult(factor_best, Dmax3D, APUncertainty, gpr_max);
            if bool_waitbar
                waitbar(0.90, f, 'Wait for visualization...');
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Visualization %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.ShowROICheckBox.Value = 1;
%             app.ShowROICheckBox.ValueChangedFcn;
            app.ShowROICheckBoxValueChanged(1)
            
            app.DrawAllResults(interp_film, interp_dose_new, gamma_max, factor_best);
            bool_imageshown = true;
            addlistener(rgamma, 'ROIMoved', @app.WatchROI);
            addlistener(rmeasured, 'ROIMoved', @app.WatchROI);
            addlistener(rcalculated, 'ROIMoved', @app.WatchROI);            
            
            if bool_save                
                name_split = split(info.RTPlan, '\');
                pYearMonth = name_split{end-3};
                pDay = name_split{end-2};
                resname = sprintf('%s_%s_%s_AutomoDQA_%2.1f.png', info.roomnum, rtplan_info.PatientID, rtplan_info.PatientName.FamilyName, golden_length);
                
                save_path_tmp = '.\save_path_tmp';                
                if ~isa(json_struct, 'double')
                    save_path_tmp = json_struct.setting.save_path;
                end
                save_path = fullfile(save_path_tmp, pYearMonth, pDay, rtplan_info.PatientName.FamilyName); % same as RTPlan
                %% if save path not exist
                if ~isfolder(save_path)
                    mkdir(save_path);
                    if bool_waitbar
                        waitbar(.95, f, 'saving...');
                    end
                end
                exportapp(app.figure1, fullfile(save_path, resname));
                fprintf("Result saved at [%s]\n", fullfile(save_path, resname));
                % save best figure
                if gpr_max>best_gpr
                    best_figure = app.figure1;
                end
            end
%             tmp_event = matlab.ui.eventdata.ValueChangedData(1, 0);                        
            
            set(app.ShowRes, 'FontColor', 'blue');
            if bool_waitbar                
                waitbar(1, f, 'Finishing...');
                close(f);
            end
        end
      
        function [gpr_max] = runDQALoop(app, film, info, measured_length, f, APrange, bool_save, best_gpr)
            
            global Dmax
            global gamma_max
            
            global interp_dose_new
            global interp_film            
                        
            global bool_imageshown
            global factor_best
            
            global rgamma
            global rmeasured
            global rcalculated
            
            len_AP_range = length(APrange);
            resfilm = cell(1, len_AP_range);
            resdose = cell(1, len_AP_range);
            resgamma = cell(1, len_AP_range);
            resfactor = cell(1, len_AP_range);
            resgpr = cell(1, len_AP_range);
            
            %% Investigate AP uncertainty
            for index=1:len_AP_range
                waitbar_msg = sprintf("Iteration (%s/%s) ....", num2str(index), num2str(len_AP_range));
                waitbar(0.2+index*0.06, f, waitbar_msg);
                [tmpfilm, tmpdose, tmpgamma, tmpfactor, tmpgprmax] = app.runDQA(film, info, measured_length+APrange(index), f, false, bool_save, best_gpr);
                
                resfilm{index} = tmpfilm;
                resdose{index} = tmpdose;
                resgamma{index} = tmpgamma;
                resfactor{index} = tmpfactor;
                resgpr{index} = tmpgprmax;
                
                if tmpgprmax>best_gpr
                    best_gpr = tmpgprmax;
                end
            end
            
%             gpr_max = max(cell2mat(resgpr));
            gpr_max = best_gpr;
            max_index = find(cell2mat(resgpr)==gpr_max);
            interp_film = resfilm{max_index};
            interp_dose_new = resdose{max_index};
            gamma_max = resgamma{max_index};
            factor_best = resfactor{max_index};
            
            best_AP_uncertainty = APrange(max_index);
            app.SetGPRResult(factor_best, Dmax, best_AP_uncertainty(1), gpr_max);
            app.DrawAllResults(interp_film, interp_dose_new, gamma_max, factor_best);
            bool_imageshown = true;
            addlistener(rgamma, 'ROIMoved', @app.WatchROI);
            addlistener(rmeasured, 'ROIMoved', @app.WatchROI);
            addlistener(rcalculated, 'ROIMoved', @app.WatchROI);
            
            waitbar(1, f, 'Finishing...');
            close(f);
            
        end
        
        function [info] = setPatientInfo(app, info, bool_updateinfo)
            
            rtplan_info = dicominfo(info.RTPlan);
            rtdose_info = dicominfo(info.RTDose);
            NumberOfFractions = rtplan_info.FractionGroupSequence.Item_1.NumberOfFractionsPlanned;
            RTDoseGridScaling = rtdose_info.DoseGridScaling;                

            rtdose_data = dicomread(rtdose_info);
            rtdose_data = squeeze(rtdose_data);
            rtdose = (RTDoseGridScaling/NumberOfFractions).*double(rtdose_data);
            rtdose = flip(rtdose,3);
            Dmax3D = max(rtdose, [], "all");
            info.Dmax3D = Dmax3D;
            
            info.stName = 'Empty-StationName';
            if ~isempty(rtdose_info.StationName) && length(rtdose_info.StationName)>3
                info.stName = rtdose_info.StationName(3:end);
            end
            
            if strcmp(info.stName, '10386')     % 41
                info.roomnum = '41';                
            elseif strcmp(info.stName, '10538') % 45
                info.roomnum = '45';                
            elseif strcmp(info.stName, '10448') % 46
                info.roomnum = '46';                
            else
                info.roomnum = "RoomNum";                
            end
            
            if bool_updateinfo
                app.updatePatientInfo(info);
            end
        end
        
        function updatePatientInfo(app, info)
            rtplan_info = dicominfo(info.RTPlan);
            format_PBirthday = sprintf("%s.%s.%s", rtplan_info.PatientBirthDate(1:4), rtplan_info.PatientBirthDate(5:6), rtplan_info.PatientBirthDate(7:end));
            NumberOfFractions = rtplan_info.FractionGroupSequence.Item_1.NumberOfFractionsPlanned;
            format_stName = sprintf("%s (%s)", info.stName, info.roomnum);
            
            set(app.MRN, 'value', rtplan_info.PatientID);
            set(app.PName, 'value', rtplan_info.PatientName.FamilyName);
            set(app.PSex, 'value', rtplan_info.PatientSex);
            set(app.PBirthday, 'value', format_PBirthday);
            set(app.TomoMachine, 'value', format_stName);
            set(app.Dmax_cal, 'value', num2str(info.Dmax3D), 'fontname', 'Calibri');
            set(app.NumFrac, 'value', num2str(NumberOfFractions));
        end
        
        function [info] = LoadInfo(app, info, pfiles_day)
            
            for index=1:length(pfiles_day)
%                 disp(pfiles_day(index))
                info.path = pfiles_day(index).folder;
                if contains(pfiles_day(index).name, "RD") && ~contains(pfiles_day(index).name, "RIT")                        
                    info.RTDose = fullfile(pfiles_day(index).folder, pfiles_day(index).name);
                elseif contains(pfiles_day(index).name, "RP")                        
                    info.RTPlan = fullfile(pfiles_day(index).folder, pfiles_day(index).name);
                elseif contains(pfiles_day(index).name, "tif")
                    if length(pfiles_day(index).name)>15
                        info.Film = fullfile(pfiles_day(index).folder, pfiles_day(index).name);
                    end
                end    
            end
                        
            if isempty(info.RTDose) || isempty(info.RTPlan) || isempty(info.Film)
                disp("Error !! there is an empty Dose / Plan / Film")
                set(app.pushbuttonSelectPatient, 'FontColor', 'red')
                return                    
            end            
        end
        
        function [DQA_DB] = LoadInfoDQADB(app, DQA_DB, patients_day)
                        
            for index=1:length(patients_day)
                                                                
                pfiles_day_path = fullfile(patients_day(index).folder, patients_day(index).name);
                pfiles_day = dir(pfiles_day_path);
                pfiles_day = pfiles_day(3:end); % exclude path
                
                info = struct;
                info = app.LoadInfo(info, pfiles_day);                   
                info = app.setPatientInfo(info, false);
                DQA_DB{index} = info;
            end            
            
        end
        
        function LoadFilmdata(app, filmpath, filmfile)
            
            global name_filmcal_data
            global filmcal_data            
            
            filmcal_path = fullfile(filmpath, filmfile);
            if isfile(filmcal_path)
                name_filmcal_data = filmfile;
                filmcal_data = LoadFilmCalFile(filmcal_path);
                set(app.pushbuttonSelectFilmCalFile, 'FontColor', 'blue');
                app.FilmCalibrationEditField.Value = filmcal_path;
                fprintf("film path : [%s]\n", fullfile(filmpath, filmfile));
            else
                fprintf("Invalid film file path < %s >\n", filmcal_path);
            end            
        end
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function TomoDQAGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to TomoDQAGUI (see VARARGIN)
            
            % Choose default command line output for TomoDQAGUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function
        function ConvertToFilmDose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ConvertToFilmDose (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global film filmcal_data filmdose x_film z_film
            
            film( film < min(filmcal_data(:,2)) ) = min(filmcal_data(:,2));
            film( film > max(filmcal_data(:,2)) ) = max(filmcal_data(:,2));
            
            filmdose = interp1(filmcal_data(:,2),filmcal_data(:,1),film);
            filmdose = filmdose./100; % cGy to Gy
            
            maxfilmdose = max(max(filmdose));
            
            axes(handles.axes1);
            imagesc(x_film,z_film,filmdose)
            colormap(gray)
            axis xy
            axis equal
            axis tight
            axis off
            set(gca,'CLim',[0,maxfilmdose])
        end

        % Button pushed function: OpenConfiguration
        function OpenConfiguration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to OpenConfiguration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global json_struct            
            global DQA_DB
            global bool_OpenConfig
            global info

            bool_OpenConfig = false;
            [config_file, config_path] = uigetfile({'*.*'});
            if isequal(config_file, 0)
                disp('Load configuration : user canceled');
                return
            end
            config = fullfile(config_path, config_file);
            app.ConfigurationEditField.Value = config;
            
            fid = fopen(config);
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);
            str=strrep(str, '/', '\');
            str=strrep(str, '\\', '\');
            str=strrep(str, '\', '\\');
            json_struct = jsondecode(str);            
            
            if strcmp(json_struct.film.path(1), '\')                
                json_struct.film.path = insertAfter(json_struct.film.path, 0, '\');
            end
            if strcmp(json_struct.patient.folder_day(1), '\')                
                json_struct.patient.folder_day = insertAfter(json_struct.patient.folder_day, 0, '\');
            end            
            fprintf("film path : [%s]\n", json_struct.film.path);
            fprintf("patient folder path : [%s]\n", json_struct.patient.folder_day);            
            if isa(json_struct, 'double')
                app.SavepathEditField.Value = '.\save_path_tmp';
            else
                app.SavepathEditField.Value = json_struct.setting.save_path;
            end
            
            f=waitbar(0, 'Please wait...');                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% handling patient info %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%             
            waitbar(.33, f, 'Loading patient info');                                                
                        
            Basepath_day = json_struct.patient.folder_day;
            patients_day = dir(Basepath_day);
            patients_day = patients_day(3:end); % exclude path             
            
            if isempty(patients_day)
                fprintf('ERROR !! invalid file/path for " %s "\n', json_struct.patient.folder_day)
                close(f);
                return
            end
            app.SelectedfolderEditField.Value = Basepath_day;
            
            DQA_DB = cell(1, length(patients_day));            
            DQA_DB = app.LoadInfoDQADB(DQA_DB, patients_day);
            info = DQA_DB{1};
            set(app.pushbuttonSelectPatient, 'FontColor', 'blue')
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% handling film %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            waitbar(.66, f, 'Loading film info');            
            app.LoadFilmdata(json_struct.film.path, json_struct.film.cal_short);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% /handling film %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            waitbar(1, f, 'Finishing');
            close(f)
                        
            bool_OpenConfig = true;
            set(app.OpenConfiguration, 'FontColor', 'blue')
        end

        % Callback function
        function OpenFilmCalibrationFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to OpenFilmCalibrationFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [filmCal_file,filmCal_path] = uigetfile('D:\MATLAB\1TomoDQA\Film\*.cal');
            
            global filmCalFile
            filmCalFile = fullfile(filmCal_path,filmCal_file);
            
            global filmcal_data
            filmcal_data = LoadFilmCalFile(filmCalFile);
        end

        % Callback function
        function Run6DOFImageRegistration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Run6DOFImageRegistration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [config_file,config_path] = uigetfile('D:\MATLAB\TomoDQA\Configuration\*.txt');
            ConfigFile = fullfile(config_path,config_file);
            
            set(handles.text2, 'String', 'Running ITK 6DOF rigid image registration...');
            
            command_ITKReg = sprintf('D:\\build\\TomoDQAReg6DOF\\Release\\TomoDQAReg6DOF "%s',ConfigFile);
            system(command_ITKReg);
            
            rotationMatrix = load('rotation.txt');
            
            rotation = RotMatZXY2EulerAnglesDegree( rotationMatrix );   % in degree
            
            translation = load('translation.txt');
            
            %rotation = load('D:\build\TomoDQARegTranslation\Release\rotation.txt');
            %translation = load('D:\build\TomoDQARegTranslation\Release\translation.txt');
            
            fprintf('Registration completed...\n\n *Resulting rotation/translation (Film-to-RTP dose)\n%f, %f, %f (deg), %f, %f, %f (mm)', ...
                rotation(1),rotation(2),rotation(3), ...
                translation(1),translation(2),translation(3))
            
            set(handles.text2, 'String', sprintf('Registration completed...\n\n *Resulting rotation, translation (Film-to-RTP dose)\n%f, %f, %f (deg), %f, %f, %f (mm)', ...
                rotation(1),rotation(2),rotation(3), ...
                translation(1),translation(2),translation(3)));
            
            %fixedImage_header = mha_read_header('fixedImage.mha');
            fixedImage = mha_read_volume('fixedImage.mha');
            
            size(fixedImage)
            
            %deformedMovingImage_header = mha_read_header('deformedMovingImage.mha');
            deformedMovingImage = mha_read_volume('deformedMovingImage.mha');
            
            size(deformedMovingImage)
            
            global x_film_corrected z_film_corrected
            global xindex_film_crop zindex_film_crop
            global x_film_corrected_cropped z_film_corrected_cropped
            
            global FilmSpacing
            %global CT
            %global x_CT y_CT z_CT
            
            x_film_corrected_cropped = x_film_corrected(xindex_film_crop);
            z_film_corrected_cropped = z_film_corrected(zindex_film_crop);
            
            filmdose = squeeze(fixedImage)';
            rtdose_reg = squeeze(deformedMovingImage)';
            
            FilmScaling = IntensityScalingMean(rtdose_reg,filmdose);
            filmdose_scaled = filmdose*FilmScaling;
            
            reference.image = filmdose_scaled;
            reference.origin = [min(x_film_corrected_cropped),min(z_film_corrected_cropped)];
            reference.spacing = [FilmSpacing,FilmSpacing];
            
            evaluated.image = rtdose_reg;
            evaluated.origin = [min(x_film_corrected_cropped),min(z_film_corrected_cropped)];
            evaluated.spacing = [FilmSpacing,FilmSpacing];
        end

        % Callback function
        function RunITKImageRegistration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to RunITKImageRegistration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global PatientName
            global MRN
            
            set(handles.text2, 'String', 'Running ITK 3DOF rigid image registration...');
            
            command_ITKReg = sprintf('D:\\build\\TomoDQARegTranslation\\Release\\TomoDQARegTranslation "%s" %s', ...
                PatientName, MRN);
            system(command_ITKReg);
            
            translation = load('D:\build\TomoDQARegTranslation\Release\translation.txt');
            
            set(handles.text2, 'String', sprintf('Registration completed...\n\n *Resulting translation (Film-to-RTP dose)\n%.1f, %.1f, %.1f (mm)',translation(1),translation(2),translation(3)));
        end

        % Callback function
        function RunImageRegistration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to RunImageRegistration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % film image for image registration
            global filmdose_cropped
            global x_film_corrected_cropped z_film_corrected_cropped
            
            global rtdose
            global x_rtdose y_rtdose z_rtdose
            global FilmAPLocation
            
            filmdose3D_cropped = zeros(1,length(x_film_corrected_cropped),length(z_film_corrected_cropped));
            for kk = 1:length(z_film_corrected_cropped)
                for ii = 1:length(x_film_corrected_cropped)
                    filmdose3D_cropped(1,ii,kk) = filmdose_cropped(kk,ii);
                end
            end
            
            R_filmdose ...
                = imref3d(size(filmdose3D_cropped), ...
                [min(x_film_corrected_cropped),max(x_film_corrected_cropped)], ...
                [FilmAPLocation, FilmAPLocation+0.001], ...
                [min(z_film_corrected_cropped),max(z_film_corrected_cropped)]);
            
            R_rtdose ...
                = imref3d(size(rtdose), ...
                [min(x_rtdose),max(x_rtdose)], ...
                [min(y_rtdose),max(y_rtdose)], ...
                [min(z_rtdose),max(z_rtdose)]);
            
            %RegType = 'monomodal';
            RegType = 'multimodal';
            % monomodal worked slightly better than multimodal for this
            % registration ... but very small difference
            
            [optimizer,metric] = imregconfig(RegType);
            
            % optimizer.MaximumStepLength = 50;
            % optimizer.MinimumStepLength = 0.001;
            % optimizer.MaximumIterations = 200;
            
            
            [rtdose_reg, R_rtdose_reg] ...
                = imregister(rtdose,R_rtdose, ...
                filmdose3D_cropped,R_filmdose, ...
                'translation',optimizer,metric,'PyramidLevels',1);
            transform ...
                = imregtform(rtdose,R_rtdose, ...
                filmdose3D_cropped,R_filmdose, ...
                'translation',optimizer,metric,'PyramidLevels',1);
            tformMatrix = transform.T
            
            x_rtdose_reg = R_rtdose_reg.XWorldLimits(1):R_rtdose_reg.PixelExtentInWorldX:R_rtdose_reg.XWorldLimits(2);
            y_rtdose_reg = R_rtdose_reg.YWorldLimits(1):R_rtdose_reg.PixelExtentInWorldY:R_rtdose_reg.YWorldLimits(2);
            z_rtdose_reg = R_rtdose_reg.ZWorldLimits(1):R_rtdose_reg.PixelExtentInWorldZ:R_rtdose_reg.ZWorldLimits(2);
            
            figure('Color','w')
            imagesc(x_film_corrected_cropped,z_film_corrected_cropped,squeeze(rtdose_reg)')
            colormap(gray)
            axis xy
            axis equal
            axis tight
        end

        % Button pushed function: SaveConfiguration
        function SaveConfiguration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %% Select a day, film calibration file.            
            path_pfolder = uigetdir('', "Select a day for DQA");
            if isequal(path_pfolder, 0)
                disp('Open Configuration : user cancled')
                return
            end     
            [filmfile, filmpath] = uigetfile({'*.*'}, 'Select a film calibration file for DQA');
            if isequal(filmfile, 0)
                disp('Open Configuration : user cancled')
                return
            end
            app.SelectedfolderEditField.Value = path_pfolder;
            
            global DQA_DB            
            
            f=waitbar(0, 'Please wait...');
            waitbar(.33, f, 'Loading Patient info');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Load patient files and save per day %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Basepath_day = path_pfolder;
            patients_day = dir(Basepath_day);
            patients_day = patients_day(3:end); % exclude path             
            
            DQA_DB = cell(1, length(patients_day));            
            DQA_DB = app.LoadInfoDQADB(DQA_DB, patients_day);
            set(app.pushbuttonSelectPatient, 'FontColor', 'blue')
            fprintf("patient folder path : [%s]\n", path_pfolder);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                        
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Load films %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            waitbar(.80, f, 'Loading film info');            
            app.LoadFilmdata(filmpath, filmfile);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            set(app.SaveConfiguration, 'FontColor', 'blue')            
            waitbar(1, f, 'Finishing');
            close(f)

        end

        % Value changed function: MRN
        function MRN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            global DTA
            DTA = str2double(get(hObject,'String'));
        end

        % Value changed function: editDoseTolerance
        function editDoseTolerance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editDoseTolerance (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editDoseTolerance as text
            %        str2double(get(hObject,'String')) returns contents of editDoseTolerance as a double
            global DoseTolerance
            DoseTolerance = 0.0;
            
            DoseTolerance = str2double(get(hObject,'String'));
        end

        % Callback function
        function pushbuttonCalculateGamma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonCalculateGamma (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            Criterion_DTA = app.editDTAtolerance.Value;
            Criterion_DD = app.editDoseTolerance.Value;
%             disp([Criterion_DTA , Criterion_DD])
            
                        
        end

        % Callback function
        function pushbuttonCropImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonCropImage (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global film
            global x_film z_film
            
            minfilmintensity = min(min(film));
            maxfilmintensity = max(max(film));
            
            axes(handles.axes1);
            imagesc(x_film,z_film,film)
            colormap(gray)
            axis xy
            axis equal
            axis tight
            axis off
            set(gca,'CLim',[minfilmintensity,maxfilmintensity])
            
            %%%%% crop
            k = waitforbuttonpress;
            point1 = get(gca,'CurrentPoint');    % button down detected
            finalRect = rbbox;                   % return figure units
            point2 = get(gca,'CurrentPoint');    % button up detected
            point1 = point1(1,1:2);              % extract x and y
            point2 = point2(1,1:2);
            p1 = min(point1,point2);             % calculate locations
            offset = abs(point1-point2);         % and dimensions
            % Find the coordinates of the box.
            xCoords = [p1(1) p1(1)+offset(1) p1(1)+offset(1) p1(1) p1(1)];
            zCoords = [p1(2) p1(2) p1(2)+offset(2) p1(2)+offset(2) p1(2)];
            x1 = round(xCoords(1));
            x2 = round(xCoords(2));
            z1 = round(zCoords(5));
            z2 = round(zCoords(3));
            hold on
            axis manual
            plot(xCoords, zCoords, 'g-'); % redraw in dataspace units
            
            xindex = 1:length(x_film);
            xindex = xindex';
            zindex = 1:length(z_film);
            zindex = zindex';
            
            global xindex_film_crop zindex_film_crop
            xindex_film_crop = xindex((x_film > x1) & (x_film < x2));
            zindex_film_crop = zindex(z_film > z1 & z_film < z2);
        end

        % Button pushed function: SaveResult
        function SaveResult_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonFilmRegistration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global info            
            global json_struct
            
            % json loaded
            if isstruct(json_struct)                
                
                rtplan_info = dicominfo(info.RTPlan);
                name_split = split(info.RTPlan, '\');
                pYearMonth = name_split{end-3};
                pDay = name_split{end-2};
                resname = sprintf('%s_AutomoDQA_%s_%s.png', info.roomnum, rtplan_info.PatientID, rtplan_info.PatientName.FamilyName);
                
                save_path = fullfile(json_struct.setting.save_path, pYearMonth, pDay); % same as RTPlan
                %% if save path not exist
                if ~isfolder(save_path)
                    mkdir(save_path);
                end
                exportapp(app.figure1, fullfile(save_path, resname));
            else
                
            end
            
        end

        % Callback function
        function pushbuttonOpenFilmImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonOpenFilmImage (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [film_file,film_path] = uigetfile('D:\MATLAB\1TomoDQA\Film\*.tif');
            
            global filename_film
            filename_film = sprintf('%s\%s',film_path,film_file);
            
            global film
            global x_film z_film
            
            t = Tiff(filename_film);
            film = read(t);
            film = flipud(film);
            film = double(film);
            film = 2^16 - film;
            
            % median filtering
            film = medfilt2(film, [5 5]);
            
            
            % film resolution: 71 dpi (dots per inch)
            %       71 dots / 1 inch = 71 dots / 25.4 mm
            global FilmSpacing
            FilmSpacing = 25.4/71;   % pixel size (mm)
            FilmSize = size(film);
            
            xindex_film = (1:FilmSize(2))';
            zindex_film = (1:FilmSize(1))';
            
            x_film = FilmSpacing*(xindex_film - FilmSize(2)/2);
            z_film = FilmSpacing*(zindex_film - FilmSize(1)/2);
            
            axes(handles.axes1);
            imagesc(x_film,z_film,film)
            colormap(gray)
            axis xy
            axis equal
            axis tight
            axis off
        end

        % Callback function
        function pushbuttonPlotDoses_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonPlotDoses (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %global film
            global filmdose
            global filmdose_cropped
            global x_film_corrected z_film_corrected
            global xindex_film_crop zindex_film_crop
            global x_film_corrected_cropped z_film_corrected_cropped
            
            global rtdose
            global rtdose_ref
            global x_rtdose y_rtdose z_rtdose
            global FilmAPLocation
            
            %global CT
            %global x_CT y_CT z_CT
            
            x_film_corrected_cropped = x_film_corrected(xindex_film_crop);
            z_film_corrected_cropped = z_film_corrected(zindex_film_crop);
            filmdose_cropped = filmdose(zindex_film_crop,xindex_film_crop);
            
            F = griddedInterpolant({y_rtdose,x_rtdose,z_rtdose},rtdose);
            rtdose_ref =  F({FilmAPLocation,x_rtdose,z_rtdose});
            rtdose_ref = squeeze(rtdose_ref)';
            
            figure('Color','w')
            imagesc(x_film_corrected_cropped,z_film_corrected_cropped,filmdose_cropped)
            colormap(gray)
            axis xy
            axis equal
            axis tight
            axis([-50 50 -50 50])
            
            figure('Color','w')
            imagesc(x_rtdose,z_rtdose,rtdose_ref)
            colormap(gray)
            axis xy
            axis equal
            axis tight
            axis([-50 50 -50 50])
            
            % for location = -50:10:50
            % figure('Color','w')
            % imagesc(x_rtdose,z_rtdose,rtdose_ref)
            % colormap(gray)
            % axis xy
            % axis equal
            % axis tight
            % axis([-50 50 -50 50])
            % end
            
            R_filmdose ...
                = imref2d(size(filmdose_cropped), ...
                [min(x_film_corrected_cropped),max(x_film_corrected_cropped)], ...
                [min(z_film_corrected_cropped),max(z_film_corrected_cropped)]);
            
            R_rtdose ...
                = imref2d(size(rtdose_ref), ...
                [min(x_rtdose),max(x_rtdose)], ...
                [min(z_rtdose),max(z_rtdose)]);
            
            figure('Color','w')
            imshowpair(filmdose_cropped,R_filmdose,rtdose_ref,R_rtdose,'Scaling','independent')
            axis xy
            axis equal
            axis tight
        end

        % Callback function
        function pushbuttonPlotProfiles_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonPlotProfiles (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes(handles.axes1);
        end

        % Callback function
        function pushbuttonSelectFilmAPLocation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSelectFilmAPLocation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global CT CTSize
            global x_CT y_CT z_CT
            
            zindex_plot = round(CTSize(3)/2);
            
            figure
            imagesc(x_CT,y_CT,CT(:,:,zindex_plot),[0 2000])
            colormap(gray)
            axis xy
            axis equal
            axis tight
            set(gca,'Ydir','reverse')
            
            % get film AP location on CT image of Cheese phantom
            uicontrol('Style', 'pushbutton', 'String', 'Set Film AP Loc',...
                'Position', [20 20 100 20],...
                'Callback', @app.chooseAPloc);
            
            % get rotation center
            uicontrol('Style', 'pushbutton', 'String', 'Set Rotation Center',...
                'Position', [50 20 100 20],...
                'Callback', @app.chooseRotationCenterXY);
            
            yindex_plot = round(CTSize(2)/2);
            
            figure
            imagesc(x_CT,z_CT,squeeze(CT(:,yindex_plot,:))',[0 2000])
            colormap(gray)
            axis xy
            axis equal
            axis tight
            %set(gca,'Ydir','reverse')
            
            % get rotation center
            uicontrol('Style', 'pushbutton', 'String', 'Set Rotation Center',...
                'Position', [20 20 100 20],...
                'Callback', @app.chooseRotationCenterZ);
                      
        end

        % Button pushed function: pushbuttonSelectPatient
        function pushbuttonSelectPatient_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSelectPatient (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             [file, path] = uigetfile('MultiSelect','on', {'*.*'});
%             [FilmFile, pathFilm] = uigetfile({'*.*'});
%             if isequal(FilmFile, 0)
%                 disp('user canceled');
%                 return
%             end

            path_pfolder = uigetdir("","Select patient folder");
            if isequal(path_pfolder, 0)
                disp('Select Patient : user cancled')
                return
            end
            
            set(app.pushbuttonSelectPatient, 'FontColor', 'black')            
            app.SelectedfolderEditField.Value = path_pfolder;
            pfiles_day = dir(path_pfolder);
            f=waitbar(0, 'Please wait...');
                        
            global info
            global json_struct

            
            waitbar(.33, f, 'Loading DICOMs...');
            info = struct;            
            info = app.LoadInfo(info, pfiles_day);
            info = app.setPatientInfo(info, true);
            
            waitbar(.66, f, 'Set information...');
            
            %% film setting
            config_path = fullfile(pwd, 'Config');
            config_file = 'AutomoDQAconfiguration.json';
            config = fullfile(config_path, config_file);
            if isfile(config) % if config file valid
                fid = fopen(config);
                raw = fread(fid, inf);
                str = char(raw');
                fclose(fid);
                str=strrep(str, '/', '\');
                str=strrep(str, '\\', '\');
                str=strrep(str, '\', '\\');
                json_struct = jsondecode(str);
                if strcmp(json_struct.film.path(1), '\')                
                    json_struct.film.path = insertAfter(json_struct.film.path, 0, '\');
                end
                
                % in case of NAS, start with \\
                if strcmp(json_struct.patient.folder_day(1), '\')                
                    json_struct.patient.folder_day = insertAfter(json_struct.patient.folder_day, 0, '\');
                end
                fprintf("film path : [%s]\n", json_struct.film.path);
                fprintf("patient folder path : [%s]\n", json_struct.patient.folder_day);                
                app.LoadFilmdata(json_struct.film.path, json_struct.film.cal_short);                
            else
                fprintf('could not find <AutomoDQAconfiguration.json> in "%s"\n', config_path);
            end
            
            %% saving info
            
            set(app.pushbuttonSelectPatient, 'FontColor', 'blue')
            waitbar(1, f, 'Finishing');
            close(f)
        end

        % Button pushed function: pushbuttonSelectFilmCalFile
        function pushbuttonSelectFilmCalFileButtonPushed(app, event)

            [file, path] = uigetfile({'*.*'});
            if isequal(file, 0)
                disp('user canceled');                
                return
            else
                disp(['User selected ', file]);                
            end
            set(app.pushbuttonSelectFilmCalFile, 'FontColor', 'black')
            
            f=waitbar(0, 'Please wait...');
            app.LoadFilmdata(path, file);
            
            waitbar(1, f, 'Finishing');
            close(f)
        end

        % Button pushed function: ShowRes
        function ShowResButtonPushed(app, event)
            
            global info
            global interp_film
            global json_struct
            
            global bool_imageshown
            global best_figure
            
            % Single patient
            % Multiple film scaling factor
            % Single or Multiple AP uncertainties
            
            best_gpr = -1;
            APUncertainty = get(app.APUncertaintyLevel, 'Value');            
            set(app.ShowRes, 'FontColor', 'black');
            
            measured_length = 94.7; % mm 
            golden_length = measured_length+APUncertainty;
                        
            bool_imageshown = false;
            
            f = waitbar(0, 'Please wait...');
            waitbar(0.22, f, 'Loading Film information...');
            
            if isa(info, 'double')
                disp('No data for patient/film.');
                close(f);
                return
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% film %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if isfile(info.Film)
                interp_film = app.FilmLoader(info.Film, 1);
                interp_film_t = interp_film;
            else
                fprintf("Invalid film path : [%s]\n", info.Film);
                close(f);
                return
            end
                    
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% /film %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.updatePatientInfo(info);
            if isa(json_struct, 'double')
                app.SavepathEditField.Value = '.\save_path_tmp';
            else
                app.SavepathEditField.Value = json_struct.setting.save_path;
            end
            
            if strcmpi(get(app.APuncertaintySwitch, 'value'), 'Off')
                % Single AP uncertainty
                waitbar(0.33, f, 'Loading Dose information...');                
                app.runDQA(interp_film_t, info, golden_length, f, true, true, best_gpr);
            else
                % Multiple AP uncertainty
                waitbar(0.2, f, 'Wait for autorun.....');                
                %% initial settings
                ap_min = app.AP_min.Value;
                ap_max = app.AP_max.Value;
                APrange = linspace(ap_min, ap_max, (ap_max-ap_min)*2+1);
                best_gpr = app.runDQALoop(interp_film_t, info, measured_length, f, APrange, true, best_gpr);
            end
            
            rtplan_info = dicominfo(info.RTPlan);
            figure_name = sprintf('GP_%2.2f_%s_AutomoDQA.png', best_gpr*100, rtplan_info.PatientName.FamilyName);
            exportapp(best_figure, fullfile(info.path, figure_name));
            
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = get(app.EditField, 'Value');
            set(app.Slider.Value, 'string', num2str(value));
        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value;            
            set(app.EditField, 'Value', value);
        end

        % Callback function
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            set(app.EditField_2, 'Value', app.Slider_2.Value);
        end

        % Callback function
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            set(app.EditField_3, 'Value', app.Slider_3.Value);
        end

        % Value changed function: ShowROICheckBox
        function ShowROICheckBoxValueChanged(app, event)
                        
            global bool_showROI
            global bool_imageshown
            global rgamma
            global rmeasured
            global rcalculated
                        
            value = app.ShowROICheckBox.Value;
            bool_showROI = value;
            
            if ishandle(rgamma)
                if bool_imageshown
                    if bool_showROI            
                        rgamma.Visible = true;
                        rmeasured.Visible = true;
                        rcalculated.Visible = true;                
                    else
                        rgamma.Visible = false;
                        rmeasured.Visible = false;
                        rcalculated.Visible = false;
                    end                    
                end
            end


        end

        % Value changed function: ConfigRoiSize
        function ConfigRoiSizeValueChanged(app, event)
            global size_film
            
            global bool_imageshown
            global rgamma
            global rmeasured
            global rcalculated
            
            global size_x_offset
            global size_y_offset
            global x_end
            global y_end
            
            if ishandle(rgamma)
                if bool_imageshown % image printed ?
%                     rgamma.Visible = false;
%                     rmeasured.Visible = false;
%                     rcalculated.Visible = false;

                    config_roi_in_percent = app.ConfigRoiSize.Value;            
                    percentage_edge_offset = config_roi_in_percent/100;
                    size_x_offset = floor(size_film(2)*percentage_edge_offset);
                    size_y_offset = floor(size_film(1)*percentage_edge_offset);
                    x_end = floor(size_film(2)-size_x_offset);
                    y_end = floor(size_film(1)-size_y_offset);
                    square_x_len = floor(size_film(2)-size_x_offset*2);
                    square_y_len = floor(size_film(1)-size_y_offset*2);
                    
                    app.MoveROI([size_x_offset, size_y_offset, square_x_len, square_y_len]);
%                     rgamma.Position = [size_x_offset, size_y_offset, square_x_len, square_y_len];
%                     rmeasured.Position  = [size_x_offset, size_y_offset, square_x_len, square_y_len];
%                     rcalculated.Position = [size_x_offset, size_y_offset, square_x_len, square_y_len];
                    
%                     rgamma = drawrectangle(app.UIAxes, 'position', [size_x_offset, size_y_offset, square_x_len, square_y_len]);
%                     rmeasured = drawrectangle(app.UIAxes_2, 'position', [size_x_offset, size_y_offset, square_x_len, square_y_len]);
%                     rcalculated = drawrectangle(app.UIAxes_3, 'position', [size_x_offset, size_y_offset, square_x_len, square_y_len]);
%                     rgamma = rectangle(app.UIAxes, 'position', [size_x_offset, size_y_offset, square_x_len, square_y_len], 'EdgeColor', 'y', 'LineStyle', ":");
%                     rmeasured = rectangle(app.UIAxes_2, 'position', [size_x_offset, size_y_offset, square_x_len, square_y_len], 'EdgeColor', 'y', 'LineStyle', ":");
%                     rcalculated = rectangle(app.UIAxes_3, 'position', [size_x_offset, size_y_offset, square_x_len, square_y_len], 'EdgeColor', 'y', 'LineStyle', ":");
%                     drawnow update

%                     addlistener(rgamma, 'ROIMoved', @app.WatchROI)
%                     addlistener(rmeasured, 'ROIMoved', @app.WatchROI)
%                     addlistener(rcalculated, 'ROIMoved', @app.WatchROI)
                    
                    rgamma.Visible = false;
                    rmeasured.Visible = false;
                    rcalculated.Visible = false;
                    
                    bool_roishown = app.ShowROICheckBox.Value;                    
                    if bool_roishown
                        rgamma.Visible = true;
                        rmeasured.Visible = true;
                        rcalculated.Visible = true;                        
                    end
                end
            end
            
        end

        % Button pushed function: pushbuttonCalculateGamma_2
        function pushbuttonCalculateGamma_2ButtonPushed(app, event)
            
            
            global Dmax
            global gamma_max
            global interp_dose_new
            global rgamma
            
            if ishandle(rgamma)
                pos = rgamma.Position;
                [yaxis, xaxis, width, height] = deal(round(pos(1)), round(pos(2)), round(pos(3)), round(pos(4)));
                
                LDcutoff_percent = str2double(app.editLowDoseTolerance.Value);                
                recal_gpr = CalGPR(gamma_max(xaxis:xaxis+height, yaxis:yaxis+width), interp_dose_new(xaxis:xaxis+height, yaxis:yaxis+width), Dmax, LDcutoff_percent);
                format_PF = sprintf("%2.1f %%", recal_gpr*100);
                
                %%% Set info %%%
                gpr_cutoff_percent = str2double(app.editGPRTolerance.Value);
                if recal_gpr >= gpr_cutoff_percent/100
                    set(app.PF, 'text', 'PASS', 'fontcolor', 'blue', 'fontname', 'Calibri');
                    set(app.Mgpr, 'text', format_PF, 'fontcolor', 'blue', 'fontname', 'Calibri');
                else
                    set(app.PF, 'text', 'FAIL', 'fontcolor', 'red', 'fontname', 'Calibri');
                    set(app.Mgpr, 'text', format_PF, 'fontcolor', 'red', 'fontname', 'Calibri');
                end                
            else
                disp('ERROR! ROI is not valid')
            end
            
        end

        % Button pushed function: pushbuttonCalculateGamma_3
        function pushbuttonCalculateGamma_3ButtonPushed(app, event)
            
           
            global interp_film
            global interp_film_avg
            global interp_dose_new
            
            global Dmax
            global gamma_max
            global rgamma
            global rmeasured
            global rcalculated
            global bool_imageshown
                        
            f = waitbar(0, 'Please wait...');
            waitbar(0.22, f, 'Preparing data...');

            size_film = size(interp_dose_new);
            
            waitbar(.55, f, 'Gamma analysis...');
            if ishandle(rgamma)
                % ROI defined already
                pos = rgamma.Position;            
                [y_from, x_from, width, height] = deal(round(pos(1)), round(pos(2)), round(pos(3)), round(pos(4)));
                x_to = x_from + height;
                y_to = y_from + width;
                
            else
                % ROI default
                config_roi_size_in_perecent = app.ConfigRoiSize.Value;
                percentage_edge_offset = config_roi_size_in_perecent/100;
                x_from = floor(size_film(1)*percentage_edge_offset);
                y_from = floor(size_film(2)*percentage_edge_offset);
                x_to = size_film(1)-x_from;
                y_to = size_film(2)-y_from;
            end
            DTA_percent = str2double(app.editDTAtolerance.Value);
            DD_percent = str2double(app.editDoseTolerance.Value);
            LDcutoff_percent = str2double(app.editLowDoseTolerance.Value);            
            [gamma_max, gpr] = app.GammaAnalyzer(interp_film_avg, interp_dose_new, app.FilmScaling.Value, x_from, y_from, x_to, y_to, DTA_percent, DD_percent, LDcutoff_percent);

            waitbar(.88, f, 'Visualizing...');
            %%% set result
            app.SetGPRResult(app.FilmScaling.Value, Dmax, str2double(app.BestAPuncertainty.Value), gpr);
            app.DrawAllResults(interp_film, interp_dose_new, gamma_max, app.FilmScaling.Value);
            bool_imageshown = true;
            addlistener(rgamma, 'ROIMoved', @app.WatchROI);
            addlistener(rmeasured, 'ROIMoved', @app.WatchROI);
            addlistener(rcalculated, 'ROIMoved', @app.WatchROI);            
                        
            waitbar(1, f, 'Finishing...');
            close(f)
            
        end

        % Value changed function: FilmScaling
        function FilmScalingValueChanged(app, event)
            value = app.FilmScaling.Value;
            
        end

        % Value changed function: SelectProfileCheckBox
        function SelectProfileCheckBoxValueChanged(app, event)
            value = app.SelectProfileCheckBox.Value;
            
            global bool_imageshown
            global roi_point
            global roi_point2
            global roi_point3
            
%             global Dmax
            global interp_film
            global interp_dose_new
            global gamma_max
            global factor_best

            global rgamma
            global rmeasured
            global rcalculated
            
            threshold_y = app.UIAxes.YLim;
            threshold_y = threshold_y(2);
            
            if bool_imageshown
                
                % del prev. point
                if ishandle(roi_point)
                    delete(roi_point)
                    delete(roi_point2)
                    delete(roi_point3)
                end
                
                if value
                    roi_point = drawpoint(app.UIAxes, 'Color', 'r', 'Visible', 'off');
                    roi_point2 = drawpoint(app.UIAxes_2, 'Color', 'r', 'Visible', 'off', 'Position', roi_point.Position);
                    roi_point3 = drawpoint(app.UIAxes_3, 'Color', 'r', 'Visible', 'off', 'Position', roi_point.Position);

                    pos_dot = roi_point.Position;
                    pos_dot = pos_dot(2);
                    
                    % Inside axes
                    if threshold_y ~= pos_dot                        
                        roi_point.Visible = true;
                        roi_point2.Visible = true;
                        roi_point3.Visible = true;
                        
                        % Update Images
                        app.DrawAllResults(interp_film, interp_dose_new, gamma_max, factor_best);
                        addlistener(rgamma, 'ROIMoved', @app.WatchROI);
                        addlistener(rmeasured, 'ROIMoved', @app.WatchROI);
                        addlistener(rcalculated, 'ROIMoved', @app.WatchROI);                        
                    else
                        delete(roi_point)
                        delete(roi_point2)
                        delete(roi_point3)
                    end                   
                                        
                    app.SelectProfileCheckBox.Value = false;
                end
            else
                app.SelectProfileCheckBox.Value = false;
            end
            
        end

        % Button pushed function: AutoRun
        function AutoRunButtonPushed(app, event)
            global DQA_DB
            global json_struct
            global info
                        
            % Check load config
            if isempty(DQA_DB)
                disp('empty patient list, please check folder path and configuration is loaded.')
                set(app.pushbuttonSelectFilmCalFile, 'FontColor', 'red');
                return
            else
                set(app.pushbuttonSelectFilmCalFile, 'FontColor', 'black');                
                global best_figure
                                                
                for index=1:length(DQA_DB)
                                                                        
                    global interp_film                    
                    global bool_imageshown
                    
                    best_gpr = -1;
                    best_figure = nan;
                    
                    % Multiple patient
                    % Multiple film scaling factor
                    % Single or Multiple AP uncertainties
                    
                    APUncertainty = get(app.APUncertaintyLevel, 'Value');
                    set(app.ShowRes, 'FontColor', 'black');
                    
                    measured_length = 94.7; % mm 
                    golden_length = measured_length+APUncertainty;
                    
                    info = DQA_DB{index};
                    bool_imageshown = false;
                    
                    f = waitbar(0, 'Please wait...');
                    waitbar(0.22, f, 'Loading Film information...');
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% film %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    interp_film = app.FilmLoader(info.Film, 1);                    
                            
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% /film %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    % set Dmax 3D
                    app.updatePatientInfo(info);
                    if isa(json_struct, 'double')
                        app.SavepathEditField.Value = '.\save_path_tmp';
                    else
                        app.SavepathEditField.Value = json_struct.setting.save_path;
                    end
                    
                    if strcmpi(get(app.APuncertaintySwitch, 'value'), 'Off')
                        waitbar(0.33, f, 'Loading Dose information...');
                        app.runDQA(interp_film, info, golden_length, f, true, true, best_gpr);
                    else                
                        waitbar(0.2, f, 'Wait for autorun.....');
                        
                        %% initial settings
                        ap_min = app.AP_min.Value;
                        ap_max = app.AP_max.Value;
                        APrange = linspace(ap_min, ap_max, (ap_max-ap_min)*2+1);                
                        
                        best_gpr = app.runDQALoop(interp_film, info, measured_length, f, APrange, true, best_gpr);                        
                    end
                    
                    rtplan_info = dicominfo(info.RTPlan);
                    figure_name = sprintf('GP_%2.2f_%s_AutomoDQA.png', best_gpr*100, rtplan_info.PatientName.FamilyName);
                    exportapp(best_figure, fullfile(info.path, figure_name));
                
                end
                set(app.pushbuttonSelectFilmCalFile, 'FontColor', 'blue');
            end
            
                        
        end

        % Value changed function: Dmax_cal
        function Dmax_calValueChanged(app, event)
            value = app.Dmax_cal.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AutomoDQA';
            app.figure1.Icon = 'sev.png';
            app.figure1.Tag = 'figure1';
            app.uipanelTomoDQA = uipanel(app.figure1);
            app.uipanelTomoDQA.Tag = 'uipanelTomoDQA';
            app.GridLayout = uigridlayout(app.uipanelTomoDQA);
            app.axes2 = uiaxes(app.GridLayout);
            app.axes2.Tag = 'axes2';
            app.axes2_2 = uiaxes(app.GridLayout);
            app.axes2_2.Tag = 'axes2';
            app.PName = uieditfield(app.GridLayout, 'text');
            app.PName.Tag = 'edit1';
            app.PName.Editable = 'off';
            app.PName.Value = 'Jone Doe';
            app.NumFrac = uieditfield(app.GridLayout, 'text');
            app.NumFrac.Tag = 'edit1';
            app.NumFrac.Editable = 'off';
            app.NumFrac.Value = '0';
            app.pushbuttonSelectPatient = uibutton(app.GridLayout, 'push');
            app.pushbuttonSelectPatient.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSelectPatient_Callback, true);
            app.pushbuttonSelectPatient.Tag = 'pushbuttonSelectPatient';
            app.pushbuttonSelectPatient.Text = 'Select Patient';
            app.uipanelGammaAnalysis = uipanel(app.GridLayout);
            app.uipanelGammaAnalysis.Title = 'Gamma Analysis';
            app.uipanelGammaAnalysis.Tag = 'uipanelGammaAnalysis';
            app.text11 = uilabel(app.uipanelGammaAnalysis);
            app.text11.Tag = 'text11';
            app.text11.Text = 'DTA tolerance (mm):';
            app.text12 = uilabel(app.uipanelGammaAnalysis);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Dose tolerance (%):';
            app.editDTAtolerance = uieditfield(app.uipanelGammaAnalysis, 'text');
            app.editDTAtolerance.Tag = 'editDTAtolerance';
            app.editDTAtolerance.Value = '3';
            app.editDoseTolerance = uieditfield(app.uipanelGammaAnalysis, 'text');
            app.editDoseTolerance.ValueChangedFcn = createCallbackFcn(app, @editDoseTolerance_Callback, true);
            app.editDoseTolerance.Tag = 'editDoseTolerance';
            app.editDoseTolerance.Value = '3';
            app.text12_2 = uilabel(app.uipanelGammaAnalysis);
            app.text12_2.Tag = 'text12';
            app.text12_2.Text = 'Low dose tolerance (%):';
            app.editLowDoseTolerance = uieditfield(app.uipanelGammaAnalysis, 'text');
            app.editLowDoseTolerance.Tag = 'editDoseTolerance';
            app.editLowDoseTolerance.Value = '10';
            app.text12_3 = uilabel(app.uipanelGammaAnalysis);
            app.text12_3.Tag = 'text12';
            app.text12_3.Text = 'GPR tolerance (%):';
            app.editGPRTolerance = uieditfield(app.uipanelGammaAnalysis, 'text');
            app.editGPRTolerance.Tag = 'editDoseTolerance';
            app.editGPRTolerance.Value = '90';
            app.OpenConfiguration = uibutton(app.GridLayout, 'push');
            app.OpenConfiguration.ButtonPushedFcn = createCallbackFcn(app, @OpenConfiguration_Callback, true);
            app.OpenConfiguration.Tag = 'OpenConfiguration';
            app.OpenConfiguration.Text = {'Load'; 'Configuration'};
            app.SaveConfiguration = uibutton(app.GridLayout, 'push');
            app.SaveConfiguration.ButtonPushedFcn = createCallbackFcn(app, @SaveConfiguration_Callback, true);
            app.SaveConfiguration.Tag = 'SaveConfiguration';
            app.SaveConfiguration.Text = {'Select'; 'Configuration'};
            app.text9 = uilabel(app.GridLayout);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Number of Fractions';
            app.text4 = uilabel(app.GridLayout);
            app.text4.Tag = 'text4';
            app.text4.Text = '  Patient Name';
            app.text3 = uilabel(app.GridLayout);
            app.text3.Tag = 'text3';
            app.text3.Text = '  MRN ';
            app.SaveResult = uibutton(app.GridLayout, 'push');
            app.SaveResult.ButtonPushedFcn = createCallbackFcn(app, @SaveResult_Callback, true);
            app.SaveResult.Tag = 'pushbuttonFilmRegistration';
            app.SaveResult.Text = 'Save Result';
            app.MRN = uieditfield(app.GridLayout, 'text');
            app.MRN.ValueChangedFcn = createCallbackFcn(app, @MRN_Callback, true);
            app.MRN.Tag = 'edit1';
            app.MRN.Editable = 'off';
            app.MRN.Value = '00000000';
            app.pushbuttonSelectFilmCalFile = uibutton(app.GridLayout, 'push');
            app.pushbuttonSelectFilmCalFile.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSelectFilmCalFileButtonPushed, true);
            app.pushbuttonSelectFilmCalFile.Tag = 'pushbuttonSelectPatient';
            app.pushbuttonSelectFilmCalFile.Text = 'Select Film Cal.';
            app.text3_2 = uilabel(app.GridLayout);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = 'Tomotherapy Machine ';
            app.TomoMachine = uieditfield(app.GridLayout, 'text');
            app.TomoMachine.Tag = 'edit1';
            app.TomoMachine.Editable = 'off';
            app.TomoMachine.Value = '0';
            app.edit1_5 = uieditfield(app.GridLayout, 'text');
            app.edit1_5.Tag = 'edit1';
            app.edit1_5.Value = 'Line profile ( Vertical )';
            app.edit1_6 = uieditfield(app.GridLayout, 'text');
            app.edit1_6.Tag = 'edit1';
            app.edit1_6.Value = 'Line profile ( Horizontal )';
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigPanel.Title = 'Configuration';
            app.ROIcropsizeLabel = uilabel(app.ConfigPanel);
            app.ROIcropsizeLabel.Text = 'ROI crop size (%)';
            app.FilmscalingfactorLabel = uilabel(app.ConfigPanel);
            app.FilmscalingfactorLabel.Text = 'Film scaling factor';
            app.APuncertaintymmLabel = uilabel(app.ConfigPanel);
            app.APuncertaintymmLabel.Text = 'AP uncertainty (mm)';
            app.EditField_2 = uieditfield(app.ConfigPanel, 'numeric');
            app.EditField_2.Value = 0.9;
            app.EditField_3 = uieditfield(app.ConfigPanel, 'numeric');
            app.EditField_3.Value = 1.1;
            app.AP_min = uieditfield(app.ConfigPanel, 'numeric');
            app.AP_min.ValueDisplayFormat = '%11.1f';
            app.AP_min.Value = -3;
            app.AP_max = uieditfield(app.ConfigPanel, 'numeric');
            app.AP_max.ValueDisplayFormat = '%11.1f';
            app.AP_max.Value = 3;
            app.Label = uilabel(app.ConfigPanel);
            app.Label.Text = '1.0';
            app.AP_default = uilabel(app.ConfigPanel);
            app.AP_default.Text = '0.0';
            app.DefaultLabel = uilabel(app.ConfigPanel);
            app.DefaultLabel.Text = 'Default';
            app.minLabel = uilabel(app.ConfigPanel);
            app.minLabel.Text = 'min';
            app.maxLabel = uilabel(app.ConfigPanel);
            app.maxLabel.Text = 'max';
            app.ConfigRoiSize = uieditfield(app.ConfigPanel, 'numeric');
            app.ConfigRoiSize.ValueChangedFcn = createCallbackFcn(app, @ConfigRoiSizeValueChanged, true);
            app.ConfigRoiSize.Value = 10;
            app.pushbuttonCalculateGamma_2 = uibutton(app.ConfigPanel, 'push');
            app.pushbuttonCalculateGamma_2.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonCalculateGamma_2ButtonPushed, true);
            app.pushbuttonCalculateGamma_2.Tag = 'pushbuttonCalculateGamma';
            app.pushbuttonCalculateGamma_2.Text = 're-calculate';
            app.GammapassratePanel = uipanel(app.GridLayout);
            app.GammapassratePanel.Title = 'Gamma pass rate';
            app.PF = uilabel(app.GammapassratePanel);
            app.PF.Text = 'N/A';
            app.Mgpr = uilabel(app.GammapassratePanel);
            app.Mgpr.Text = '- %';
            app.BestconfigurationPanel = uipanel(app.GridLayout);
            app.BestconfigurationPanel.Title = 'Best configuration';
            app.BestAPuncertainty = uieditfield(app.BestconfigurationPanel, 'text');
            app.BestAPuncertainty.Tag = 'editDTAtolerance';
            app.BestAPuncertainty.Editable = 'off';
            app.BestAPuncertainty.Value = '-';
            app.BestFilmScalingFactor = uieditfield(app.BestconfigurationPanel, 'text');
            app.BestFilmScalingFactor.Tag = 'editDTAtolerance';
            app.BestFilmScalingFactor.Editable = 'off';
            app.BestFilmScalingFactor.Value = '-';
            app.text3_4 = uilabel(app.BestconfigurationPanel);
            app.text3_4.Tag = 'text3';
            app.text3_4.Text = 'Film Scaling factor';
            app.text3_6 = uilabel(app.BestconfigurationPanel);
            app.text3_6.Tag = 'text3';
            app.text3_6.Text = 'AP uncertainty (mm)';
            app.text3_7 = uilabel(app.GridLayout);
            app.text3_7.Tag = 'text3';
            app.text3_7.Text = 'Dmax for cal. dose (Gy) ';
            app.Dmax_cal = uieditfield(app.GridLayout, 'text');
            app.Dmax_cal.ValueChangedFcn = createCallbackFcn(app, @Dmax_calValueChanged, true);
            app.Dmax_cal.Tag = 'edit1';
            app.Dmax_cal.Editable = 'off';
            app.Dmax_cal.Value = 'N/A';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DGammaanalysisTab = uitab(app.TabGroup);
            app.DGammaanalysisTab.Title = '2D Gamma analysis';
            app.UIAxes = uiaxes(app.DGammaanalysisTab);
            app.MeasureddoseTab = uitab(app.TabGroup);
            app.MeasureddoseTab.Title = 'Measured dose';
            app.UIAxes_2 = uiaxes(app.MeasureddoseTab);
            app.CaclulateddoseTab = uitab(app.TabGroup);
            app.CaclulateddoseTab.Title = 'Caclulated dose';
            app.UIAxes_3 = uiaxes(app.CaclulateddoseTab);
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.SelectedfolderEditFieldLabel = uilabel(app.InformationTab);
            app.SelectedfolderEditFieldLabel.Text = 'Selected folder';
            app.SelectedfolderEditField = uieditfield(app.InformationTab, 'text');
            app.SelectedfolderEditField.Editable = 'off';
            app.FilmCalibrationEditFieldLabel = uilabel(app.InformationTab);
            app.FilmCalibrationEditFieldLabel.Text = 'Film Calibration';
            app.FilmCalibrationEditField = uieditfield(app.InformationTab, 'text');
            app.FilmCalibrationEditField.Editable = 'off';
            app.ConfigurationEditFieldLabel = uilabel(app.InformationTab);
            app.ConfigurationEditFieldLabel.Text = 'Configuration';
            app.ConfigurationEditField = uieditfield(app.InformationTab, 'text');
            app.ConfigurationEditField.Editable = 'off';
            app.SavepathEditFieldLabel = uilabel(app.InformationTab);
            app.SavepathEditFieldLabel.Text = 'Save path';
            app.SavepathEditField = uieditfield(app.InformationTab, 'text');
            app.SavepathEditField.Editable = 'off';
            app.RobustregistrationSwitch_2 = uiswitch(app.GridLayout, 'slider');
            app.RobustregistrationSwitch_2Label = uilabel(app.GridLayout);
            app.RobustregistrationSwitch_2Label.Text = 'Robust registration';
            app.text9_2 = uilabel(app.GridLayout);
            app.text9_2.Tag = 'text9';
            app.text9_2.Text = 'Patient Sex';
            app.PSex = uieditfield(app.GridLayout, 'text');
            app.PSex.Tag = 'edit1';
            app.PSex.Editable = 'off';
            app.PSex.Value = '0';
            app.text9_3 = uilabel(app.GridLayout);
            app.text9_3.Tag = 'text9';
            app.text9_3.Text = 'Patient Birthday';
            app.PBirthday = uieditfield(app.GridLayout, 'text');
            app.PBirthday.Tag = 'edit1';
            app.PBirthday.Editable = 'off';
            app.PBirthday.Value = '0';
            app.APUncertaintyLevel = uieditfield(app.GridLayout, 'numeric');
            app.APUncertaintyLevel.ValueDisplayFormat = '%1.1f';
            app.APadjustmmLabel = uilabel(app.GridLayout);
            app.APadjustmmLabel.Text = 'AP adjust (mm)';
            app.FilmScaleLabel = uilabel(app.GridLayout);
            app.FilmScaleLabel.Text = 'Film Scale';
            app.FilmScaling = uieditfield(app.GridLayout, 'numeric');
            app.FilmScaling.ValueDisplayFormat = '%1.2f';
            app.FilmScaling.ValueChangedFcn = createCallbackFcn(app, @FilmScalingValueChanged, true);
            app.FilmScaling.Value = 1;
            app.pushbuttonCalculateGamma_3 = uibutton(app.GridLayout, 'push');
            app.pushbuttonCalculateGamma_3.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonCalculateGamma_3ButtonPushed, true);
            app.pushbuttonCalculateGamma_3.Tag = 'pushbuttonCalculateGamma';
            app.pushbuttonCalculateGamma_3.Text = 'adjust film';
            app.ShowROICheckBox = uicheckbox(app.GridLayout);
            app.ShowROICheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowROICheckBoxValueChanged, true);
            app.ShowROICheckBox.Text = 'Show ROI';
            app.SelectProfileCheckBox = uicheckbox(app.GridLayout);
            app.SelectProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectProfileCheckBoxValueChanged, true);
            app.SelectProfileCheckBox.Text = 'Select Profile';
            app.ShowRes = uibutton(app.GridLayout, 'push');
            app.ShowRes.ButtonPushedFcn = createCallbackFcn(app, @ShowResButtonPushed, true);
            app.ShowRes.Tag = 'pushbuttonSelectPatient';
            app.ShowRes.Text = 'Run DQA';
            app.AutoRun = uibutton(app.GridLayout, 'push');
            app.AutoRun.ButtonPushedFcn = createCallbackFcn(app, @AutoRunButtonPushed, true);
            app.AutoRun.Tag = 'pushbuttonCalculateGamma';
            app.AutoRun.Text = 'AutoRun';
            app.APuncertaintySwitchLabel = uilabel(app.GridLayout);
            app.APuncertaintySwitchLabel.Text = 'AP uncertainty';
            app.APuncertaintySwitch = uiswitch(app.GridLayout, 'slider');
            app.SaveResult_2 = uibutton(app.GridLayout, 'push');
            app.SaveResult_2.Tag = 'pushbuttonFilmRegistration';
            app.SaveResult_2.Text = {'Save'; 'Configuration'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutomoDQA(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)TomoDQAGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end